classdef simulation_ui2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SetsimulationPanel            matlab.ui.container.Panel
        AgentsEditFieldLabel          matlab.ui.control.Label
        AgentsEditField               matlab.ui.control.NumericEditField
        AttackersEditFieldLabel       matlab.ui.control.Label
        AttackersEditField            matlab.ui.control.NumericEditField
        StartsimulationButton         matlab.ui.control.Button
        ReplaysimulationButton        matlab.ui.control.Button
        SetnumberofiterationsSpinnerLabel  matlab.ui.control.Label
        SetnumberofiterationsSpinner  matlab.ui.control.Spinner
    end

    
    properties (Access = private)
        Env % multi-robot environment
        NumOfRob; % total # of robots
        NumOfAgents; % number of agents
        OcMap; % Occupancy map
        FieldLen = 100;% length of field side
        PZoneLen = 20;% Protected Zone side length
        pZoneMaxX; % Protected Zone max x position
        pZoneMinX; % Protected Zone min x position
        pZoneMaxY; % Protected Zone max y position
        pZoneMinY; % Protected Zone min y position
        Poses; % Position matrix for the robots
        FrameSize = 10;
        FrameInitAg = 0.2; % Frame ratio for initial agent positions
        LifeFlags; % Life markers for the robots
        SimTime = 60; % total simulation time (sec)
        SampleTime = 0.1; % time stemp (sec)
        TimeVec; % time vector
        ATC_EVADE_RAD = 10; % attackers evade radius
    end
    
    methods (Access = private)
        
        % return position for attacker
        function res = get_atacker_init_pos(app)
            pos1 = (rand(1,1)-0.5)*2; % get rand in [-1,1]
            negIdx = (pos1<0);
            posIdx = (pos1>=0);
            % map to [-10,0] or [100,110]
            pos1 = app.FrameSize .* pos1;
            pos1 = ((app.FieldLen/2)+ pos1).*posIdx ...
                + (-(app.FieldLen/2)+ pos1).*negIdx  ...
                + app.FieldLen/2;
            % map to [-10,110]
            pos2 = (-app.FrameSize+(app.FieldLen+(2*app.FrameSize)) .* rand(1,1));
            
            coinFlip = (rand(1,1)-0.5)*2;
            if coinFlip >= 0
                res = [pos1; pos2;pi*rand(1,1)];
            else
                res = [pos2; pos1;pi*rand(1,1)];
            end
        end
        
        % return position for defender
        function pos = get_agent_init_pos(app)
            offset = app.FrameInitAg * app.FieldLen;
            frameSize = offset*2+app.PZoneLen;
            pos = [(rand(2, 1))*frameSize+offset;pi*rand(1,1)];
        end
        
        
        function w=calc_angular_speed_by_point(app,pos,point)
            y = point(2)-pos(2);
            x = point(1)-pos(1);
            theta = atan2(y,x);
            theta = mod(theta - mod(pos(3),2*pi),2*pi);
            if theta > pi
                theta = -(2*pi -theta);
            end
            w = interp1([-pi,pi],[-1,1],theta);
        end
        
        % get relevent point of protected zone
        function target = get_point_of_pzone(app,point)
            curr_x = point(1);
            curr_y = point(2);
            if app.pZoneMinX <= curr_x && curr_x <= app.pZoneMaxX
                x = curr_x;
            elseif curr_x > app.pZoneMaxX
                x = app.pZoneMaxX;
            else
                x = app.pZoneMinX;
            end 
            
            if app.pZoneMinY <= curr_y && curr_y <= app.pZoneMaxY
                y = curr_y;
            elseif curr_x > app.pZoneMaxY
                y = app.pZoneMaxY;
            else
                y = app.pZoneMinY;
            end
            target = [x y];
        end
        
%         function idx = get_closest_point(app,points)
%             min_dis = Inf;
%             idx = 0;
%             for i=1:length(points)
%                 % get relevent point
%                 pZone = app.get_point_of_pzone(points(i,:));
%                 % calc distance
%                 curr_dis = norm(pZone - points(i));
%                 % update current
%                 if curr_dis < min_dis
%                     idx = i;
%                     min_dis = curr_dis;
%                 end
%             end
%         end
        
        function [w,evade] = calc_atk_angular_speed(app,radius,pos,robots)
            w = 0;
            evade = false;
            temp_w = 0;
            heading = mod(pos(3),2*pi);
            % run on all robots
            for i=1:length(robots)
                u = robots(:,i) - pos(1:2);
                v = [0;1];
                % calc distance
                distance = norm(u);
                if distance <= radius
                    % calc angle between the vectors
                    cos_theta = max(min(dot(u,v)/distance,1),-1);
                    theta = real(acosd(cos_theta));
                    r_pos = robots(:,i);
                    if r_pos(2) < pos(2)
                        theta = theta + pi;   
                    elseif r_pos(2) == pos(2)
                        if r_pos(1) < pos(1)
                            theta = theta + pi;
                        end
                    end
                    theta = mod(theta,2*pi);
                    diff = heading - theta;
                    if diff >= -pi/3 && diff <=  0
                       temp_w = interp1([0,pi/3],[0.5,2],pi/3+diff);
                       if  abs(temp_w) > abs(w)
                           w = temp_w;
                           evade = true;
                       end
                    elseif diff <= pi/3 && diff > 0
                       w = interp1([0,pi/3],[-2,-0.5],pi/3-diff);
                       evade = true;
                       break;
                    end
                end
            end
        end
        
        % attackers controler
        function vel = AtCtrl(app, rIdx)
            pos = app.Poses(:,rIdx);
            
            % check if there is a defender in radius of 3 meters, if there
            % is evade
            defenders_pos = app.Poses(1:2,1:app.NumOfAgents);
            [w,evade] = app.calc_atk_angular_speed(app.ATC_EVADE_RAD,pos,defenders_pos);
            if ~evade
                % move toward protected zone
                % get point to move to
                target = app.get_point_of_pzone(pos);
                w = app.calc_angular_speed_by_point(pos,target); 
            end
            vel = bodyToWorld([1;0;w],pos);
        end
        
        % agents controler
        function vel = AgCtrl(app, rIdx)
             pose = app.Poses(:,rIdx);
            vel = bodyToWorld([0;0;0],pose);
        end
        
        % robots controler
        function vel = robCtrl(app, numAgs,vel)
            for i=1:app.NumOfRob
                if i <= numAgs
                    vel(:,i) = app.AgCtrl(i);
                elseif app.LifeFlags(i) == 1
                    vel(:,i) = app.AtCtrl(i);
                end
            end
        end
        
        % run the simulation loop
        function simulation_loop(app, numAgs)
            vel=zeros(3, app.NumOfRob);
            % run on each timestamp
            vor = voronoi(app.Poses(1,:)',app.Poses(2,:)');
            for currStemp =2:numel(app.TimeVec)
                % update env
                app.Env(1:app.NumOfRob, app.Poses);
                xlim([0 100]);   % Without this, axis resizing can slow things down
                ylim([0 100]); 
                
                % display voronoi
                set(vor,'Visible','off')
                hold on
                vor = voronoi(app.Poses(1,:)',app.Poses(2,:)');
                set(vor,'Visible','on')
                %clf
                % run robot controller
                vel = app.robCtrl(numAgs,vel);

                % Calculate next timestamp poses
                app.Poses = app.Poses + vel*app.SampleTime;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % create occupancy map
            app.OcMap = binaryOccupancyMap(app.FieldLen, app.FieldLen);
            assignin("base","map", app.OcMap)
            fieldMid = fix(app.FieldLen/2);
            pzMid = fix (app.PZoneLen/2);
            x = fieldMid-pzMid+1:fieldMid+pzMid;
            y = fieldMid-pzMid+1:fieldMid+pzMid;
            app.pZoneMaxX = x(end);
            app.pZoneMinX = x(1);
            app.pZoneMaxY = y(end);
            app.pZoneMinY = y(1);
            [p,q] = meshgrid(x,y);
            xy = [p(:) q(:)];
            setOccupancy(app.OcMap, xy, ones(app.PZoneLen^2,1))
        end

        % Button pushed function: StartsimulationButton
        function StartsimulationButtonPushed(app, event)

            
            %% Create a multi-robot environment
            
            % get User values
            numOfAgents = app.AgentsEditField.Value;
            numOfAttackers = app.AttackersEditField.Value;
            mumIter = app.SetnumberofiterationsSpinner.Value;
            
            app.NumOfRob = numOfAttackers+numOfAgents;
            app.NumOfAgents = numOfAgents;
            
            % init multi-robot environment
            app.Env = MultiRobotEnv(app.NumOfRob);
            app.Env.robotRadius = 1 ;
            app.Env.showTrajectory = false;
            app.Env.mapName = "map";
            
            % divide robots to groups
            a = repmat([0 0 1],[numOfAgents 1]);
            b = repmat([1 0 0],[numOfAttackers 1]);
            app.Env.robotColors = [a;b];
            % Initialize poses
            app.Poses= [];
            for i=1:app.NumOfRob
                if i<= numOfAgents
                    pos = app.get_agent_init_pos();
                    app.Poses=[app.Poses pos];
                else
                    pos = app.get_atacker_init_pos();
                    app.Poses=[app.Poses pos];
                end
            end
            
            % set time vector
            app.TimeVec = 0:app.SampleTime:app.SimTime;
            % initialize life markers
            app.LifeFlags = ones(app.NumOfRob,1);
            
            app.simulation_loop(numOfAgents)
            
%             figure
%             show(app.OcMap)
%             
%             hold on
%             voronoi(app.Poses(:, 1),app.Poses(:,2))
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SetsimulationPanel = uipanel(app.UIFigure);
            app.SetsimulationPanel.Title = 'Set simulation';
            app.AgentsEditFieldLabel = uilabel(app.SetsimulationPanel);
            app.AgentsEditFieldLabel.Text = 'Agents';
            app.AgentsEditField = uieditfield(app.SetsimulationPanel, 'numeric');
            app.AgentsEditField.Value = 2;
            app.AttackersEditFieldLabel = uilabel(app.SetsimulationPanel);
            app.AttackersEditFieldLabel.Text = 'Attackers';
            app.AttackersEditField = uieditfield(app.SetsimulationPanel, 'numeric');
            app.AttackersEditField.Value = 1;
            app.StartsimulationButton = uibutton(app.SetsimulationPanel, 'push');
            app.StartsimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartsimulationButtonPushed, true);
            app.StartsimulationButton.Text = 'Start simulation';
            app.ReplaysimulationButton = uibutton(app.SetsimulationPanel, 'push');
            app.ReplaysimulationButton.Text = 'Replay simulation';
            app.SetnumberofiterationsSpinnerLabel = uilabel(app.SetsimulationPanel);
            app.SetnumberofiterationsSpinnerLabel.Text = 'Set number of iterations';
            app.SetnumberofiterationsSpinner = uispinner(app.SetsimulationPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simulation_ui2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end